classdef app_positions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RobotEditField              matlab.ui.control.NumericEditField
        RobotEditFieldLabel         matlab.ui.control.Label
        y20EditField                matlab.ui.control.NumericEditField
        y20EditFieldLabel           matlab.ui.control.Label
        x20EditField                matlab.ui.control.NumericEditField
        x20EditFieldLabel           matlab.ui.control.Label
        y19EditField                matlab.ui.control.NumericEditField
        y19EditFieldLabel           matlab.ui.control.Label
        x19EditField                matlab.ui.control.NumericEditField
        x19EditFieldLabel           matlab.ui.control.Label
        y18EditField                matlab.ui.control.NumericEditField
        y18EditFieldLabel           matlab.ui.control.Label
        x18EditField                matlab.ui.control.NumericEditField
        x18EditFieldLabel           matlab.ui.control.Label
        y17EditField                matlab.ui.control.NumericEditField
        y17EditFieldLabel           matlab.ui.control.Label
        x17EditField                matlab.ui.control.NumericEditField
        x17EditFieldLabel           matlab.ui.control.Label
        y16EditField                matlab.ui.control.NumericEditField
        y16EditFieldLabel           matlab.ui.control.Label
        x16EditField                matlab.ui.control.NumericEditField
        x16EditFieldLabel           matlab.ui.control.Label
        y15EditField                matlab.ui.control.NumericEditField
        y15EditFieldLabel           matlab.ui.control.Label
        x15EditField                matlab.ui.control.NumericEditField
        x15EditFieldLabel           matlab.ui.control.Label
        y14EditField                matlab.ui.control.NumericEditField
        y14EditFieldLabel           matlab.ui.control.Label
        x14EditField                matlab.ui.control.NumericEditField
        x14EditFieldLabel           matlab.ui.control.Label
        y13EditField                matlab.ui.control.NumericEditField
        y13EditFieldLabel           matlab.ui.control.Label
        x13EditField                matlab.ui.control.NumericEditField
        x13EditFieldLabel           matlab.ui.control.Label
        y12EditField                matlab.ui.control.NumericEditField
        y12EditFieldLabel           matlab.ui.control.Label
        x12EditField                matlab.ui.control.NumericEditField
        x12EditFieldLabel           matlab.ui.control.Label
        y11EditField                matlab.ui.control.NumericEditField
        y11EditFieldLabel           matlab.ui.control.Label
        x11EditField                matlab.ui.control.NumericEditField
        x11EditFieldLabel           matlab.ui.control.Label
        y10EditField                matlab.ui.control.NumericEditField
        y10EditFieldLabel           matlab.ui.control.Label
        x10EditField                matlab.ui.control.NumericEditField
        x10EditFieldLabel           matlab.ui.control.Label
        y9EditField                 matlab.ui.control.NumericEditField
        y9EditFieldLabel            matlab.ui.control.Label
        x9EditField                 matlab.ui.control.NumericEditField
        x9EditFieldLabel            matlab.ui.control.Label
        y8EditField                 matlab.ui.control.NumericEditField
        y8EditFieldLabel            matlab.ui.control.Label
        x8EditField                 matlab.ui.control.NumericEditField
        x8EditFieldLabel            matlab.ui.control.Label
        y7EditField                 matlab.ui.control.NumericEditField
        y7EditFieldLabel            matlab.ui.control.Label
        x7EditField                 matlab.ui.control.NumericEditField
        x7EditFieldLabel            matlab.ui.control.Label
        y6EditField                 matlab.ui.control.NumericEditField
        y6EditFieldLabel            matlab.ui.control.Label
        x6EditField                 matlab.ui.control.NumericEditField
        x6EditFieldLabel            matlab.ui.control.Label
        y5EditField                 matlab.ui.control.NumericEditField
        y5EditFieldLabel            matlab.ui.control.Label
        x5EditField                 matlab.ui.control.NumericEditField
        x5EditFieldLabel            matlab.ui.control.Label
        y4EditField                 matlab.ui.control.NumericEditField
        y4EditFieldLabel            matlab.ui.control.Label
        x4EditField                 matlab.ui.control.NumericEditField
        x4EditFieldLabel            matlab.ui.control.Label
        y3EditField                 matlab.ui.control.NumericEditField
        y3EditFieldLabel            matlab.ui.control.Label
        x3EditField                 matlab.ui.control.NumericEditField
        x3EditFieldLabel            matlab.ui.control.Label
        y2EditField                 matlab.ui.control.NumericEditField
        y2EditFieldLabel            matlab.ui.control.Label
        x2EditField                 matlab.ui.control.NumericEditField
        x2EditFieldLabel            matlab.ui.control.Label
        y1EditField                 matlab.ui.control.NumericEditField
        y1EditFieldLabel            matlab.ui.control.Label
        x1EditField                 matlab.ui.control.NumericEditField
        x1EditFieldLabel            matlab.ui.control.Label
        GroupEditField              matlab.ui.control.NumericEditField
        GroupEditFieldLabel         matlab.ui.control.Label
        TeamEditField               matlab.ui.control.NumericEditField
        TeamEditFieldLabel          matlab.ui.control.Label
        NumberofpositionsEditField  matlab.ui.control.NumericEditField
        NumberofpositionsEditFieldLabel  matlab.ui.control.Label
        CreatetxtfileButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatetxtfileButton
        function CreatetxtfileButtonPushed(app, event)
            robot = app.RobotEditField.Value;
            n = app.NumberofpositionsEditField.Value;
            group = app.GroupEditField.Value;
            team = app.TeamEditField.Value;
            XY = zeros(n,3);
            XY(1,3) = group;
            XY(2,3) = team;
            XY(3,3) = robot;
           
            for index=1:1:n
                px = sprintf('app.x%dEditField',index);
                XY(index,1) = get(eval(px),'Value');
                py = sprintf('app.y%dEditField',index);
                XY(index,2) = get(eval(py),'Value');
            end
            filename = sprintf('XY_%d_%d_%d.txt',[group,team,robot]);
            writematrix(XY,filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreatetxtfileButton = uibutton(app.UIFigure, 'push');
            app.CreatetxtfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatetxtfileButtonPushed, true);
            app.CreatetxtfileButton.Tag = 'CreateTXTfile';
            app.CreatetxtfileButton.Text = 'Create .txt file';
            app.NumberofpositionsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpositionsEditFieldLabel.Text = 'Number of positions';
            app.NumberofpositionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofpositionsEditField.Limits = [1 20];
            app.NumberofpositionsEditField.RoundFractionalValues = 'on';
            app.NumberofpositionsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpositionsEditField.Value = 1;
            app.TeamEditFieldLabel = uilabel(app.UIFigure);
            app.TeamEditFieldLabel.Text = 'Team';
            app.TeamEditField = uieditfield(app.UIFigure, 'numeric');
            app.TeamEditField.ValueDisplayFormat = '%.0f';
            app.TeamEditField.Value = 1;
            app.GroupEditFieldLabel = uilabel(app.UIFigure);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.UIFigure, 'numeric');
            app.GroupEditField.ValueDisplayFormat = '%.0f';
            app.GroupEditField.Value = 1;
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField.ValueDisplayFormat = '%.10f';
            app.x1EditField.Value = -1.248064453125;
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.y1EditField.ValueDisplayFormat = '%.10f';
            app.y1EditField.Value = -1.25824875809935;
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField.ValueDisplayFormat = '%.10f';
            app.x2EditField.Value = -1.256869140625;
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.y2EditField.ValueDisplayFormat = '%.10f';
            app.y2EditField.Value = -0.495088282937365;
            app.x3EditFieldLabel = uilabel(app.UIFigure);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField.ValueDisplayFormat = '%.10f';
            app.x3EditField.Value = -1.248064453125;
            app.y3EditFieldLabel = uilabel(app.UIFigure);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.UIFigure, 'numeric');
            app.y3EditField.ValueDisplayFormat = '%.10f';
            app.y3EditField.Value = 1.28239940604752;
            app.x4EditFieldLabel = uilabel(app.UIFigure);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.UIFigure, 'numeric');
            app.x4EditField.ValueDisplayFormat = '%.10f';
            app.x4EditField.Value = 0.00660351562499972;
            app.y4EditFieldLabel = uilabel(app.UIFigure);
            app.y4EditFieldLabel.Text = 'y4';
            app.y4EditField = uieditfield(app.UIFigure, 'numeric');
            app.y4EditField.ValueDisplayFormat = '%.10f';
            app.y4EditField.Value = 1.24858849892009;
            app.x5EditFieldLabel = uilabel(app.UIFigure);
            app.x5EditFieldLabel.Text = 'x5';
            app.x5EditField = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField.ValueDisplayFormat = '%.10f';
            app.x5EditField.Value = 0.750599609375;
            app.y5EditFieldLabel = uilabel(app.UIFigure);
            app.y5EditFieldLabel.Text = 'y5';
            app.y5EditField = uieditfield(app.UIFigure, 'numeric');
            app.y5EditField.ValueDisplayFormat = '%.10f';
            app.y5EditField.Value = 1.23892823974082;
            app.x6EditFieldLabel = uilabel(app.UIFigure);
            app.x6EditFieldLabel.Text = 'x6';
            app.x6EditField = uieditfield(app.UIFigure, 'numeric');
            app.x6EditField.ValueDisplayFormat = '%.10f';
            app.x6EditField.Value = 0.741794921875;
            app.y6EditFieldLabel = uilabel(app.UIFigure);
            app.y6EditFieldLabel.Text = 'y6';
            app.y6EditField = uieditfield(app.UIFigure, 'numeric');
            app.y6EditField.ValueDisplayFormat = '%.10f';
            app.y6EditField.Value = 0.292222840172786;
            app.x7EditFieldLabel = uilabel(app.UIFigure);
            app.x7EditFieldLabel.Text = 'x7';
            app.x7EditField = uieditfield(app.UIFigure, 'numeric');
            app.x7EditField.ValueDisplayFormat = '%.10f';
            app.x7EditField.Value = 0.00660351562499972;
            app.y7EditFieldLabel = uilabel(app.UIFigure);
            app.y7EditFieldLabel.Text = 'y7';
            app.y7EditField = uieditfield(app.UIFigure, 'numeric');
            app.y7EditField.ValueDisplayFormat = '%.10f';
            app.y7EditField.Value = 0.00241506479481668;
            app.x8EditFieldLabel = uilabel(app.UIFigure);
            app.x8EditFieldLabel.Text = 'x8';
            app.x8EditField = uieditfield(app.UIFigure, 'numeric');
            app.x8EditField.ValueDisplayFormat = '%.10f';
            app.x8EditField.Value = 0.662552734375;
            app.y8EditFieldLabel = uilabel(app.UIFigure);
            app.y8EditFieldLabel.Text = 'y8';
            app.y8EditField = uieditfield(app.UIFigure, 'numeric');
            app.y8EditField.ValueDisplayFormat = '%.10f';
            app.y8EditField.Value = -0.0169054535637145;
            app.x9EditFieldLabel = uilabel(app.UIFigure);
            app.x9EditFieldLabel.Text = 'x9';
            app.x9EditField = uieditfield(app.UIFigure, 'numeric');
            app.x9EditField.ValueDisplayFormat = '%.10f';
            app.x9EditField.Value = 1.190833984375;
            app.y9EditFieldLabel = uilabel(app.UIFigure);
            app.y9EditFieldLabel.Text = 'y9';
            app.y9EditField = uieditfield(app.UIFigure, 'numeric');
            app.y9EditField.ValueDisplayFormat = '%.10f';
            app.y9EditField.Value = -1.00708201943844;
            app.x10EditFieldLabel = uilabel(app.UIFigure);
            app.x10EditFieldLabel.Text = 'x10';
            app.x10EditField = uieditfield(app.UIFigure, 'numeric');
            app.x10EditField.ValueDisplayFormat = '%.10f';
            app.x10EditField.Value = 1.904013671875;
            app.y10EditFieldLabel = uilabel(app.UIFigure);
            app.y10EditFieldLabel.Text = 'y10';
            app.y10EditField = uieditfield(app.UIFigure, 'numeric');
            app.y10EditField.ValueDisplayFormat = '%.10f';
            app.y10EditField.Value = -0.403315820734341;
            app.x11EditFieldLabel = uilabel(app.UIFigure);
            app.x11EditFieldLabel.Text = 'x11';
            app.x11EditField = uieditfield(app.UIFigure, 'numeric');
            app.x11EditField.ValueDisplayFormat = '%.10f';
            app.x11EditField.Value = 1.190833984375;
            app.y11EditFieldLabel = uilabel(app.UIFigure);
            app.y11EditFieldLabel.Text = 'y11';
            app.y11EditField = uieditfield(app.UIFigure, 'numeric');
            app.y11EditField.ValueDisplayFormat = '%.10f';
            app.y11EditField.Value = -1.18579681425486;
            app.x12EditFieldLabel = uilabel(app.UIFigure);
            app.x12EditFieldLabel.Text = 'x12';
            app.x12EditField = uieditfield(app.UIFigure, 'numeric');
            app.x12EditField.ValueDisplayFormat = '%.10f';
            app.x12EditField.Value = -0.00220117187499991;
            app.y12EditFieldLabel = uilabel(app.UIFigure);
            app.y12EditFieldLabel.Text = 'y12';
            app.y12EditField = uieditfield(app.UIFigure, 'numeric');
            app.y12EditField.ValueDisplayFormat = '%.10f';
            app.y12EditField.Value = -1.25;
            app.x13EditFieldLabel = uilabel(app.UIFigure);
            app.x13EditFieldLabel.Text = 'x13';
            app.x13EditField = uieditfield(app.UIFigure, 'numeric');
            app.x13EditField.ValueDisplayFormat = '%.10f';
            app.y13EditFieldLabel = uilabel(app.UIFigure);
            app.y13EditFieldLabel.Text = 'y13';
            app.y13EditField = uieditfield(app.UIFigure, 'numeric');
            app.y13EditField.ValueDisplayFormat = '%.10f';
            app.x14EditFieldLabel = uilabel(app.UIFigure);
            app.x14EditFieldLabel.Text = 'x14';
            app.x14EditField = uieditfield(app.UIFigure, 'numeric');
            app.x14EditField.ValueDisplayFormat = '%.10f';
            app.y14EditFieldLabel = uilabel(app.UIFigure);
            app.y14EditFieldLabel.Text = 'y14';
            app.y14EditField = uieditfield(app.UIFigure, 'numeric');
            app.y14EditField.ValueDisplayFormat = '%.10f';
            app.x15EditFieldLabel = uilabel(app.UIFigure);
            app.x15EditFieldLabel.Text = 'x15';
            app.x15EditField = uieditfield(app.UIFigure, 'numeric');
            app.x15EditField.ValueDisplayFormat = '%.10f';
            app.y15EditFieldLabel = uilabel(app.UIFigure);
            app.y15EditFieldLabel.Text = 'y15';
            app.y15EditField = uieditfield(app.UIFigure, 'numeric');
            app.y15EditField.ValueDisplayFormat = '%.10f';
            app.x16EditFieldLabel = uilabel(app.UIFigure);
            app.x16EditFieldLabel.Text = 'x16';
            app.x16EditField = uieditfield(app.UIFigure, 'numeric');
            app.x16EditField.ValueDisplayFormat = '%.10f';
            app.y16EditFieldLabel = uilabel(app.UIFigure);
            app.y16EditFieldLabel.Text = 'y16';
            app.y16EditField = uieditfield(app.UIFigure, 'numeric');
            app.y16EditField.ValueDisplayFormat = '%.10f';
            app.x17EditFieldLabel = uilabel(app.UIFigure);
            app.x17EditFieldLabel.Text = 'x17';
            app.x17EditField = uieditfield(app.UIFigure, 'numeric');
            app.x17EditField.ValueDisplayFormat = '%.10f';
            app.y17EditFieldLabel = uilabel(app.UIFigure);
            app.y17EditFieldLabel.Text = 'y17';
            app.y17EditField = uieditfield(app.UIFigure, 'numeric');
            app.y17EditField.ValueDisplayFormat = '%.10f';
            app.x18EditFieldLabel = uilabel(app.UIFigure);
            app.x18EditFieldLabel.Text = 'x18';
            app.x18EditField = uieditfield(app.UIFigure, 'numeric');
            app.x18EditField.ValueDisplayFormat = '%.10f';
            app.y18EditFieldLabel = uilabel(app.UIFigure);
            app.y18EditFieldLabel.Text = 'y18';
            app.y18EditField = uieditfield(app.UIFigure, 'numeric');
            app.y18EditField.ValueDisplayFormat = '%.10f';
            app.x19EditFieldLabel = uilabel(app.UIFigure);
            app.x19EditFieldLabel.Text = 'x19';
            app.x19EditField = uieditfield(app.UIFigure, 'numeric');
            app.x19EditField.ValueDisplayFormat = '%.10f';
            app.y19EditFieldLabel = uilabel(app.UIFigure);
            app.y19EditFieldLabel.Text = 'y19';
            app.y19EditField = uieditfield(app.UIFigure, 'numeric');
            app.y19EditField.ValueDisplayFormat = '%.10f';
            app.x20EditFieldLabel = uilabel(app.UIFigure);
            app.x20EditFieldLabel.Text = 'x20';
            app.x20EditField = uieditfield(app.UIFigure, 'numeric');
            app.x20EditField.ValueDisplayFormat = '%.10f';
            app.y20EditFieldLabel = uilabel(app.UIFigure);
            app.y20EditFieldLabel.Text = 'y20';
            app.y20EditField = uieditfield(app.UIFigure, 'numeric');
            app.y20EditField.ValueDisplayFormat = '%.10f';
            app.RobotEditFieldLabel = uilabel(app.UIFigure);
            app.RobotEditFieldLabel.Text = 'Robot';
            app.RobotEditField = uieditfield(app.UIFigure, 'numeric');
            app.RobotEditField.ValueDisplayFormat = '%.0f';
            app.RobotEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_positions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end